classdef FourierSeriesExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierSeriesExplorerUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ComplexcoefficientsCheckBox    matlab.ui.control.CheckBox
        WaveformperiodsSliderLabel     matlab.ui.control.Label
        WaveformperiodsSlider          matlab.ui.control.Slider
        SamplingperiodsEditFieldLabel  matlab.ui.control.Label
        SamplingperiodsEditField       matlab.ui.control.NumericEditField
        NumberofharmonicsSpinnerLabel  matlab.ui.control.Label
        NumberofharmonicsSpinner       matlab.ui.control.Spinner
        ResultsTable                   matlab.ui.control.Table
        RightPanel                     matlab.ui.container.Panel
        WaveformAxes                   matlab.ui.control.UIAxes
        CoefficientsAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private, Constant)
        MaxTime = 20  % seconds
    end
    
    properties (Access = private, Dependent)
        %MaxNumHarmonics Maximum number of harmonics
        %   Define maximum number of harmonics to support. This depends on
        %   the sampling period.
        MaxNumHarmonics
    end
    
    methods (Access = private)
        
        function run(app, Ts, T, N)
            [f, t] = getWaveform(app, Ts, T);
            plot(app.WaveformAxes, t, f)
            
            if ~app.ComplexcoefficientsCheckBox.Value
                [a,b] = analyzeWaveform(app, Ts, T, N);
                stem(app.CoefficientsAxes, 0:N, a)
                hold(app.CoefficientsAxes, "on")
                stem(app.CoefficientsAxes, 0:N, b)
                hold(app.CoefficientsAxes, "off")
                legend(app.CoefficientsAxes, 'a_n', 'b_n')
                
                [f_s, t] = symthesizeWaveform(app, Ts, T, N, a, b);
            else
                c = analyzeWaveform(app, Ts, T, N);
                stem(app.CoefficientsAxes, -N:N, real(c))
                hold(app.CoefficientsAxes, "on")
                stem(app.CoefficientsAxes, -N:N, imag(c))
                hold(app.CoefficientsAxes, "off")
                legend(app.CoefficientsAxes, 'real(c)', 'imag(c)')
                
                [f_s, t] = symthesizeWaveform(app, Ts, T, N, c);
                f_s = real(f_s); % remove the small complex part due
                % to numerical errors
            end
            hold(app.WaveformAxes,"on")
            plot(app.WaveformAxes,t,f_s)
            hold(app.WaveformAxes,"off")
            legend(app.WaveformAxes, 'f(t)', 'f_s(t)')
            
            rmsError = mean(abs(f - f_s).^2);
            app.ResultsTable.Data{2,2} = rmsError;
        end
        
        function f = getFunctionValues(app,t,T)
            A = 2;
            f = zeros(size(t));
            f(t < (T/2)) = A;           % Set f(t) to A for t values less than T/2.
            f((t>=(T/2)) & (t<T)) = -A; % Set f(t) to -A for t values greater than T/2 and less than T
            
            % Check if t has values greater than T
            if any(t > T)
                f(mod(t,T) < (T/2)) = A;           % Set f(t) to A for t values less than T/2.
                f(mod(t,T)>=(T/2)) = -A; % Set f(t) to -A for t values greater than T/2 and less than T
            end
        end
        
        function varargout = analyzeWaveform(app, Ts, T, N)
            t = 0:Ts:T-Ts;
            
            f = getFunctionValues(app,t,T);
            
            if ~app.ComplexcoefficientsCheckBox.Value
                idx = 1;                    % Index for a and b vectors
                a = zeros(1,N+1);           % Preallocate for speed
                b = zeros(1,N+1);           % Preallocate for speed
                for n = 0:N
                    cosTerm = cos(2*pi*t*n/T);
                    sinTerm = sin(2*pi*t*n/T);
                    a(idx) = (2*Ts/T)*sum(f .* cosTerm);    % Integrate from t = 0 to T
                    b(idx) = (2*Ts/T)*sum(f .* sinTerm);    % Integrate from t = 0 to T
                    idx = idx + 1;
                end
                varargout{1} = a;
                varargout{2} = b;
            else
                idx = 1;
                c = zeros(1,2*N+1);
                for n = -N:N
                    expTerm = exp(-1i*2*pi*t*n/T);
                    c(idx) = (Ts/T)*sum(f .* expTerm);
                    idx = idx + 1;
                end
                varargout{1} = c;
            end
        end
        
        function [f_s,t] = symthesizeWaveform(app, Ts, T, N, varargin)
            t = 0:Ts:app.MaxTime;
            if ~app.ComplexcoefficientsCheckBox.Value
                a = varargin{1};
                b = varargin{2};
                f_s = (a(1)/2)*ones(size(t));
                for n=1:N
                    cosTerm = cos(2*pi*t*n/T);
                    sinTerm = sin(2*pi*t*n/T);
                    % Accumulate f_s with the nth term. Since n starts from 0, we use index
                    % n+1 in MATLAB, which uses 1-based indexing.
                    f_s = f_s  + a(n +1)*cosTerm + b(n +1)*sinTerm;
                end
            else
                c = varargin{1};
                f_s = zeros(size(t));
                idx = 1;
                for n=-N:N
                    expTerm = exp(1i*2*pi*t*n/T);
                    f_s = f_s  + c(idx)*expTerm;
                    idx = idx + 1;
                end
            end
        end
        
        function [f, t] = getWaveform(app, Ts, T)
            t = 0:Ts:app.MaxTime;
            f = getFunctionValues(app,t,T);
        end
    end
    
    methods
        
        function val = get.MaxNumHarmonics(app)
            Ts = app.SamplingperiodsEditField.Value;
            T = app.WaveformperiodsSlider.Value;
            val = T / (2*Ts);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Ts = app.SamplingperiodsEditField.Value;
            T = app.WaveformperiodsSlider.Value;
            N = floor(app.NumberofharmonicsSpinner.Value);
            app.ResultsTable.Data{1,1} = 'Fundamental Frequency (Hz)';
            app.ResultsTable.Data{1,2} = 1/T;
            app.ResultsTable.Data{2,1} = 'RMS Synthesis Error';
            
            run(app, Ts, T, N);
        end

        % Value changed function: SamplingperiodsEditField
        function SamplingperiodsEditFieldValueChanged(app, event)
            Ts = event.Value;
            T = app.WaveformperiodsSlider.Value;
            Nmax = floor(T / (2*Ts));
            N = floor(app.NumberofharmonicsSpinner.Value);
            if N > Nmax
                app.NumberofharmonicsSpinner.Value = Nmax;
            end
            app.NumberofharmonicsSpinner.Limits = [0 Nmax];
            run(app, Ts, T, N);
        end

        % Value changing function: WaveformperiodsSlider
        function WaveformperiodsSliderValueChanging(app, event)
            T = event.Value;
            Ts = app.SamplingperiodsEditField.Value;
            Nmax = floor(T / (2*Ts));
            N = floor(app.NumberofharmonicsSpinner.Value);
            if N > Nmax
                app.NumberofharmonicsSpinner.Value = Nmax;
            end
            app.NumberofharmonicsSpinner.Limits = [0 Nmax];
            run(app, Ts, T, N);
        end

        % Value changing function: NumberofharmonicsSpinner
        function NumberofharmonicsSpinnerValueChanging(app, event)
            Ts = app.SamplingperiodsEditField.Value;
            T = app.WaveformperiodsSlider.Value;
            N = floor(event.Value);
            run(app, Ts, T, N);
        end

        % Value changed function: ComplexcoefficientsCheckBox
        function ComplexcoefficientsCheckBoxValueChanged(app, event)
            Ts = app.SamplingperiodsEditField.Value;
            T = app.WaveformperiodsSlider.Value;
            N = floor(app.NumberofharmonicsSpinner.Value);
            run(app, Ts, T, N);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.FourierSeriesExplorerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {464, 464};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {291, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourierSeriesExplorerUIFigure = uifigure('Visible', 'off');
            app.FourierSeriesExplorerUIFigure.Name = 'Fourier Series Explorer';
            app.FourierSeriesExplorerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FourierSeriesExplorerUIFigure.Tag = 'FourierSeriesExplorer';
            app.GridLayout = uigridlayout(app.FourierSeriesExplorerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ComplexcoefficientsCheckBox = uicheckbox(app.LeftPanel);
            app.ComplexcoefficientsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComplexcoefficientsCheckBoxValueChanged, true);
            app.ComplexcoefficientsCheckBox.Text = 'Complex coefficients';
            app.WaveformperiodsSliderLabel = uilabel(app.LeftPanel);
            app.WaveformperiodsSliderLabel.Text = 'Waveform period (s):';
            app.WaveformperiodsSlider = uislider(app.LeftPanel);
            app.WaveformperiodsSlider.Limits = [1 20];
            app.WaveformperiodsSlider.MajorTicks = [1 5 10 15 20];
            app.WaveformperiodsSlider.MajorTickLabels = {'1', '5', '10', '15', '20'};
            app.WaveformperiodsSlider.ValueChangingFcn = createCallbackFcn(app, @WaveformperiodsSliderValueChanging, true);
            app.WaveformperiodsSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.WaveformperiodsSlider.Value = 10;
            app.SamplingperiodsEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingperiodsEditFieldLabel.Text = 'Sampling period (s):';
            app.SamplingperiodsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingperiodsEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingperiodsEditFieldValueChanged, true);
            app.SamplingperiodsEditField.Value = 0.01;
            app.NumberofharmonicsSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofharmonicsSpinnerLabel.Text = 'Number of harmonics:';
            app.NumberofharmonicsSpinner = uispinner(app.LeftPanel);
            app.NumberofharmonicsSpinner.ValueChangingFcn = createCallbackFcn(app, @NumberofharmonicsSpinnerValueChanging, true);
            app.NumberofharmonicsSpinner.Limits = [0 500];
            app.NumberofharmonicsSpinner.Value = 30;
            app.ResultsTable = uitable(app.LeftPanel);
            app.ResultsTable.ColumnName = {'Property'; 'Value'};
            app.ResultsTable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.WaveformAxes = uiaxes(app.RightPanel);
            title(app.WaveformAxes, 'Fourier Series Approximation')
            xlabel(app.WaveformAxes, 't (s)')
            ylabel(app.WaveformAxes, 'f(t)')
            app.CoefficientsAxes = uiaxes(app.RightPanel);
            title(app.CoefficientsAxes, 'Fourier Series Coefficients')
            xlabel(app.CoefficientsAxes, 'Coefficient Index, n')
            ylabel(app.CoefficientsAxes, 'Real Coefficients')
            % Show the figure after all components are created
            app.FourierSeriesExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierSeriesExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierSeriesExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierSeriesExplorerUIFigure)
        end
    end
end